classdef Ad_Rr < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        Menu            matlab.ui.container.Menu
        SlopeButton     matlab.ui.control.Button
        RrButton        matlab.ui.control.Button
        AdButton        matlab.ui.control.Button
        DropDown        matlab.ui.control.DropDown
        DropDownLabel   matlab.ui.control.Label
        SavedataLabel   matlab.ui.control.Label
        SaveDataButton  matlab.ui.control.Button
        LoaddataLabel   matlab.ui.control.Label
        LoadDataButton  matlab.ui.control.Button
        UIAxes3         matlab.ui.control.UIAxes
        UIAxes2         matlab.ui.control.UIAxes
        UIAxes1         matlab.ui.control.UIAxes
        ContextMenu     matlab.ui.container.ContextMenu
        Menu_2          matlab.ui.container.Menu
        Menu2           matlab.ui.container.Menu
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            close all;
%             clear;
            clc;
            warning off;

            % Input the folder to work with:
            main_dir=uigetdir('C:\Users\tsofi\OneDrive\01_ENHAnCE\07_ExperimentalData');
            folders = dir(main_dir);
            pathstr = folders(1).folder;

            % Check the number of folders and files to process
            nb_folders=0;
            nb_files =zeros();
            for i=1:length (folders)
                if (folders(i).isdir && ~strcmp(folders(i).name,'.') && ~strcmp(folders(i).name,'..'))
                    folder_name = folders(i).name;
                    folderpath = fullfile(pathstr,folder_name);
                    files = dir(folderpath);
                    nb_files(i) = length (files);
                    nb_folders=nb_folders+1;
                end
            end


            %% Load text files, process them, plot and store the results
            [C_Avg_Ad, C_Avg_Rr,C_strAd,C_strRr, C_m,C_max_pks, C_Fr_pks] = deal(cell(nb_folders, (max(nb_files)-2)));

            for i1 = 3:length(folders)% Because first two entries are '.' and '..'

                if (folders(i1).isdir) % Check if it is a folder/directorty
                    if ( ~strcmp(folders(i1).name,'.') && ~strcmp(folders(i1).name,'..'))
                        % Load all working folders
                        folder_name = folders(i1).name;
                        folderpath = fullfile(pathstr,folder_name);
                        files = dir(folderpath);

                        for i2 = 3:length(files) % Because first two entries are '.' and '..'
                            file_name = files(i2).name;
                            filename = fullfile(folderpath, file_name);
                            [folderpath] = fileparts(filename);

                            [Testdata, data, Fr, Avg_Ad ,Avg_Rr] = Avg_val(filename);% Function Avg_val loads & obtains the average of Ad & Rr for each measuremmet
                            C_Avg_Ad {(i1-2),(i2-2)}=Avg_Ad;
                            C_Avg_Rr {(i1-2),(i2-2)}=Avg_Rr;
                            [R,m,Pev]=Leastsq_Regression(Fr,Avg_Ad);% R = Correlation coefficient, m =slope
                            C_m {(i1-2),(i2-2)}= m; % Cell to store the slope values

                            % Normalize (Change plot types Lines 104 and 109 in case of normalization)
                            %                 Avg_Ad = (Avg_Ad-min(Avg_Ad))/(max(Avg_Ad)-min(Avg_Ad));
                            %                 Avg_Rr = (Avg_Rr-min(Avg_Rr))/(max(Avg_Rr)-min(Avg_Rr));

                            % Create the name matrix in order to generate the legend for the corresponding data while plotting
                            str = strsplit(filename,filesep);
                            foldername=str{1,end-1};
                            filename = str{1,end};
                            filename=filename(1:(end-4));

                            strAd = strcat(foldername,'_',filename,'_','AvgAd');
                            strRr = strcat(foldername,'_',filename,'_','AvgRr');
                            C_strAd {(i1-2),(i2-2)}=strAd;
                            C_strRr {(i1-2),(i2-2)}=strRr;

                            % Find the peaks of the signals and the respective frequencies at which those peaks occur
                            idx1 = Fr>(2e5) & Fr<(4.5e5);
                            pks = findpeaks(Avg_Rr(idx1)); % Find the peaks between a specefic Frequency range (200K-450K)
                            C_max_pks {(i1-2),(i2-2)}= max(pks);
                            max_pks = ismember(Avg_Rr,max(pks));
                            idx2 = max_pks ;
                            C_Fr_pks {(i1-2),(i2-2)} = Fr(idx2);

                            % Store all data with their names in the specfic folders
                            p = mfilename('fullpath');
                            p = fileparts([p,mfilename]);

                            sav_folder1 =strcat(foldername,'AvgAd-Plots');
                            sav_folder2 =strcat(foldername,'AvgRr-Plots');

                            mkdir((sav_folder1))
                            cd (strcat(p,'\',sav_folder1))
                            save(sprintf(strAd),'Avg_Ad') % Directly give path here in save function?

                            cd (p);
                            mkdir((sav_folder2))
                            cd (strcat(p,'\',sav_folder2))
                            save(sprintf(strRr),'Avg_Rr')
                            cd (p);

                            %% Plot the data
%                             set(0,'DefaultFigureWindowStyle','docked')% "normal" to Un-dock
%                             figure ((2*i1-5))
                            %                 figure ((i1-2))
                            %                 subplot(2,1,1);
                            txt = (erase(filename,"_"));
                            loglog (app.UIAxes1,Fr,Avg_Ad,'DisplayName',txt,'LineWidth',1)
%                             [~] = Plot_prop(sav_folder1,Fr,'Admittance [S]');
                            hold(app.UIAxes1,'on')

%                             figure ((2*i1-4))
                            %                 subplot(2,1,2);
                           semilogy (app.UIAxes2,Fr,Avg_Rr,'DisplayName',txt,'LineWidth',1)
%                             [~] = Plot_prop(sav_folder2,Fr,'Resistance [Ohm]');
                            hold(app.UIAxes2,'on')

                            % Linear fit of data to the plots, store m (Slopes) values and compare the curves based on their m(Slopes)
                            if strcmp (folder_name, 'BaseLine_Data')==1
%                                 figure((2*nb_folders)+1);
                                plot(app.UIAxes3,Fr,Pev,'DisplayName',txt,'LineWidth',1);
% %                                 [~] = Plot_prop(sav_folder1,Fr,'Admittance [Ohm]');
                                hold(app.UIAxes3,'on')
                            end


                        end
                    end
                end


            end

            %% Deviation and difference between different plots and signals
            % Deviation


            %% Function definitions
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Function for reading data %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%             function [Testdata, data, Fr, Avg_Ad ,Avg_Rr] = Avg_val(filename)
% 
%                 % Summary of this function goes here:
%                 %{
%                 - This function calculates the Capacitance,average impedance and admittance for each measurement 
%                 - Input = Text file to be processed
%                 - Output = Average values of the frequnecy, admittance and the impedance vector
%                 %}
% 
%                 %% Open and read file
%                 fid = fopen(filename,'r');
%                 data = textscan(fid, '%s %s %s %s %s %s %s %s','delimiter',',');
%                 fclose(fid);
% 
%                 % Find the number of data points in a single measurement
%                 data1= data{1,1}; % contains the test  and the frequency data
%                 Testdata = data1(2:7);
%                 datapts = char(data1(4,1));
%                 ind = find(contains(data1,datapts));
%                 d = ind(2)-ind(1); % Common difference
%                 datapts = str2double(datapts(end-3:end));% Obtain the number of data points
%                 k= round(length(data1)/(datapts)); % Initial 8 line contain the test data, automate this 8 as well by finding the loaction of the header lines
%                 datapts = datapts+8;
% 
%                 %% Laod all the test data
%                 % Capcitance
%                 Fr = data1(9:datapts);% Frequency data
%                 Fr = str2double(Fr);
%                 Ad = data{1,3}; % Admittance data
%                 Rr = data{1,5}; % Resistance data
% 
%                 %% Read each maeasurement and find the mean values of the loops
%                 [C_Ad, C_Rr] = deal(cell(1,k));
% 
%                 % Loop for each repeated measurements load the values using a loop
%                 for j=1:k % number of total data sets
%                     Ad_new = Ad((9+(j-1)*d):(j*datapts-(j-1)*3));% Arthmetic progression a_n = a_1 +(n-1)*d
%                     Ad_new = str2double(Ad_new);
%                     C_Ad{j} =Ad_new;
% 
%                     Rr_new = Rr((9+(j-1)*d):(j*datapts-(j-1)*3));
%                     Rr_new = str2double(Rr_new);
%                     C_Rr{j} = Rr_new;
%                 end
% 
%                 Avg_Ad = mean(cat(3,C_Ad{:}),3);
%                 Avg_Rr = mean(cat(3,C_Rr{:}),3);
% 
%             end
% 
%             %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Function for linear fit to data %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%             function [R,m,Pev]=Leastsq_Regression(x,y)
% 
%                 % Linear regression function
%                 % Checks and fits a linear curve between two data sets
% 
%                 p = polyfit(x,y,1);
%                 m = p(1); % slope of line fitted line (y = mx + c)
%                 % c = p(2); % constant of the fitted line (y = mx + c)
% 
%                 % Polynomial evaluation
%                 Pev = polyval(p,x);
% 
%                 % Correlation coefficients
%                 R=corrcoef(x,y);
%                 R=R(1,2);
% 
%             end
% 
%             %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Function for plot properties %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%             function [xlim1] = Plot_prop(sav_folder,Fr,Ylabel)
% 
%                 %{
%                 - To plot the admittance, the resistance and other graphs with specific properties
%                 %}
% 
%                 yticks = get(gca,'YTick');
%                 set(gca,'YTickLabel',yticks);
%                 xticks = get(gca,'XTick');
%                 set(gca,'XTickLabel',xticks);
%                 xlabel ('Frequency [Hz]','FontSize', 20)
%                 ylabel (Ylabel,'FontSize', 20)
%                 xlim1=Fr(end,1);
%                 xlim ([xlim1 Fr(1,1)])
%                 title (erase(sav_folder,'_'))
%                 legend show
%                 legend('Location','Best')
%                 hold on
% 
%             end


        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            
        end

        % Drop down opening function: DropDown
        function DropDownOpening(app, event)
            
        end

        % Button pushed function: AdButton
        function AdButtonPushed(app, event)

        end

        % Button pushed function: RrButton
        function RrButtonPushed(app, event)
            
        end

        % Button pushed function: SlopeButton
        function SlopeButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.UIAxes1 = uiaxes(app.UIFigure);
            title(app.UIAxes1, 'Admittance Plots')
            xlabel(app.UIAxes1, 'Fr [Hz]')
            ylabel(app.UIAxes1, 'Admittance [S]')
            zlabel(app.UIAxes1, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Resistance Plots')
            xlabel(app.UIAxes2, 'Fr [Hz]')
            ylabel(app.UIAxes2, 'Resistance [Ohm]')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Slope Plots')
            xlabel(app.UIAxes3, 'Fr [Hz]')
            ylabel(app.UIAxes3, 'Slope')
            zlabel(app.UIAxes3, 'Z')
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'LoadData';
            app.LoaddataLabel = uilabel(app.UIFigure);
            app.LoaddataLabel.Text = 'Load data';
            app.SaveDataButton = uibutton(app.UIFigure, 'push');
            app.SaveDataButton.Text = 'SaveData';
            app.SavedataLabel = uilabel(app.UIFigure);
            app.SavedataLabel.Text = 'Save data';
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Admittance', 'Resistance', 'Slopes', 'Option 4'};
            app.DropDown.DropDownOpeningFcn = createCallbackFcn(app, @DropDownOpening, true);
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Admittance';
            app.AdButton = uibutton(app.UIFigure, 'push');
            app.AdButton.ButtonPushedFcn = createCallbackFcn(app, @AdButtonPushed, true);
            app.AdButton.Text = 'Ad';
            app.RrButton = uibutton(app.UIFigure, 'push');
            app.RrButton.ButtonPushedFcn = createCallbackFcn(app, @RrButtonPushed, true);
            app.RrButton.Text = 'Rr';
            app.SlopeButton = uibutton(app.UIFigure, 'push');
            app.SlopeButton.ButtonPushedFcn = createCallbackFcn(app, @SlopeButtonPushed, true);
            app.SlopeButton.Text = 'Slope';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu_2 = uimenu(app.ContextMenu);
            app.Menu_2.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.LoadDataButton.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Ad_Rr

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end